classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PickdODandIDButton         matlab.ui.control.Button
        SearchbestcoilButton       matlab.ui.control.Button
        CenterPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        UIAxes_7                   matlab.ui.control.UIAxes
        UIAxes_8                   matlab.ui.control.UIAxes
        UIAxes_9                   matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        dmmEditFieldLabel          matlab.ui.control.Label
        dmmEditField               matlab.ui.control.NumericEditField
        d_ODRXEditFieldLabel       matlab.ui.control.Label
        d_ODRXEditField            matlab.ui.control.NumericEditField
        d_ODRXEditFieldLabel_2     matlab.ui.control.Label
        IDRX_ODRXEditField_2       matlab.ui.control.NumericEditField
        IDRX_ODRXEditFieldLabel    matlab.ui.control.Label
        IDRX_ODRXEditFieldLabel_2  matlab.ui.control.Label
        IDRX_ODRXEditFieldLabel_3  matlab.ui.control.Label
        ODTX_ODRXEditField         matlab.ui.control.NumericEditField
        IDTX_ODTXEditField         matlab.ui.control.NumericEditField
        ODTX_ODRXEditFieldLabel    matlab.ui.control.Label
        IDTX_ODTXEditFieldLabel    matlab.ui.control.Label
        ODTX_ODRXEditFieldLabel_2  matlab.ui.control.Label
        ODTX_ODRXEditFieldLabel_3  matlab.ui.control.Label
        IDTX_ODTXEditFieldLabel_2  matlab.ui.control.Label
        IDTX_ODTXEditFieldLabel_3  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = private)
        function axes_pte2x4(app)
            title(app.UIAxes, 'PTE vs Thickness_{RX}')
            xlabel(app.UIAxes, 'Thickness_{RX}')
            ylabel(app.UIAxes, 'PTE')
            app.UIAxes.FontName = 'Arial';
            app.UIAxes.FontSize = 10;
            app.UIAxes.TickDir = 'out';
            app.UIAxes.TickLength = [0.02 0.025];
            app.UIAxes.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.LineWidth = 0.25;
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Position = [36 535 250 185];

            %UIAxes_2
            title(app.UIAxes_2, 'PTE vs Thickness_{TX}')
            xlabel(app.UIAxes_2, 'Thickness_{TX}')
            ylabel(app.UIAxes_2, 'PTE')
            app.UIAxes_2.FontName = 'Arial';
            app.UIAxes_2.FontSize = 10;
            app.UIAxes_2.TickDir = 'out';
            app.UIAxes_2.TickLength = [0.02 0.025];
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_2.LineWidth = 0.25;
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';
            app.UIAxes_2.Position = [380 533 250 185];

            %UIAxes_3
            title(app.UIAxes_3, 'PTE vs W_{RX}')
            xlabel(app.UIAxes_3, 'W_{RX}')
            ylabel(app.UIAxes_3, 'PTE')
            app.UIAxes_3.FontName = 'Arial';
            app.UIAxes_3.FontSize = 10;
            app.UIAxes_3.TickDir = 'out';
            app.UIAxes_3.TickLength = [0.02 0.025];
            app.UIAxes_3.XTick = [0 2.5 5 7.5 10 12.5 15];
            app.UIAxes_3.XTickLabel = {'0'; '2.5'; '5'; '7.5'; '10'; '12.5'; '15'};
            app.UIAxes_3.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_3.LineWidth = 0.25;
            app.UIAxes_3.XGrid = 'on';
            app.UIAxes_3.YGrid = 'on';
            app.UIAxes_3.Position = [36 330 250 185];

            %UIAxes_4
            title(app.UIAxes_4, 'PTE vs W_{TX}')
            xlabel(app.UIAxes_4, 'W_{TX}')
            ylabel(app.UIAxes_4, 'PTE')
            app.UIAxes_4.FontName = 'Arial';
            app.UIAxes_4.FontSize = 10;
            app.UIAxes_4.TickDir = 'out';
            app.UIAxes_4.TickLength = [0.02 0.025];
            app.UIAxes_4.GridAlpha = 0.15;
            app.UIAxes_4.MinorGridAlpha = 0.25;
            app.UIAxes_4.LineWidth = 0.25;
            app.UIAxes_4.XGrid = 'on';
            app.UIAxes_4.YGrid = 'on';
            app.UIAxes_4.ZGrid = 'on';
            app.UIAxes_4.Position = [380 330 250 185];

            %UIAxes_5
            title(app.UIAxes_5, 'PTE vs N_{RX}')
            xlabel(app.UIAxes_5, 'N_{RX}')
            ylabel(app.UIAxes_5, 'PTE')
            app.UIAxes_5.FontName = 'Arial';
            app.UIAxes_5.FontSize = 10;
            app.UIAxes_5.TickDir = 'out';
            app.UIAxes_5.TickLength = [0.02 0.025];
            app.UIAxes_5.GridAlpha = 0.15;
            app.UIAxes_5.MinorGridAlpha = 0.25;
            app.UIAxes_5.LineWidth = 0.25;
            app.UIAxes_5.XGrid = 'on';
            app.UIAxes_5.YGrid = 'on';
            app.UIAxes_5.ZGrid = 'on';
            app.UIAxes_5.Position = [36 230 250 185];

            %UIAxes_6
            title(app.UIAxes_6, 'PTE vs N_{TX}')
            xlabel(app.UIAxes_6, 'N_{TX}')
            ylabel(app.UIAxes_6, 'PTE')
            app.UIAxes_6.FontName = 'Arial';
            app.UIAxes_6.FontSize = 10;
            app.UIAxes_6.TickDir = 'out';
            app.UIAxes_6.TickLength = [0.02 0.025];
            app.UIAxes_6.GridAlpha = 0.15;
            app.UIAxes_6.MinorGridAlpha = 0.25;
            app.UIAxes_6.LineWidth = 0.25;
            app.UIAxes_6.XGrid = 'on';
            app.UIAxes_6.YGrid = 'on';
            app.UIAxes_6.ZGrid = 'on';
            app.UIAxes_6.Position = [380 230 250 185];

            %UIAxes_7
            title(app.UIAxes_7, 'PTE vs Spacing_{RX}')
            xlabel(app.UIAxes_7, 'Spacing_{RX}')
            ylabel(app.UIAxes_7, 'PTE')
            app.UIAxes_7.FontName = 'Arial';
            app.UIAxes_7.FontSize = 10;
            app.UIAxes_7.TickDir = 'out';
            app.UIAxes_7.TickLength = [0.02 0.025];
            app.UIAxes_7.GridAlpha = 0.15;
            app.UIAxes_7.MinorGridAlpha = 0.25;
            app.UIAxes_7.LineWidth = 0.25;
            app.UIAxes_7.XGrid = 'on';
            app.UIAxes_7.YGrid = 'on';
            app.UIAxes_7.ZGrid = 'on';
            app.UIAxes_7.Position = [36 30 250 185];

            %UIAxes_8
            title(app.UIAxes_8, 'PTE vs Spacing_{TX}')
            xlabel(app.UIAxes_8, 'Spacing_{TX}')
            ylabel(app.UIAxes_8, 'PTE')
            app.UIAxes_8.FontName = 'Arial';
            app.UIAxes_8.FontSize = 10;
            app.UIAxes_8.TickDir = 'out';
            app.UIAxes_8.TickLength = [0.02 0.025];
            app.UIAxes_8.GridAlpha = 0.15;
            app.UIAxes_8.MinorGridAlpha = 0.25;
            app.UIAxes_8.LineWidth = 0.25;
            app.UIAxes_8.XGrid = 'on';
            app.UIAxes_8.YGrid = 'on';
            app.UIAxes_8.ZGrid = 'on';
            app.UIAxes_8.Position = [380 30 250 185];

            %UIAxes_9
            title(app.UIAxes_9, 'ID_{TX} for maximum k')
            xlabel(app.UIAxes_9, 'd/OD_{RX}')
            ylabel(app.UIAxes_9, 'ID_{TX}/OD_{TX}')
            app.UIAxes_9.FontName = 'Arial';
            app.UIAxes_9.FontSize = 10;
            app.UIAxes_9.TickDir = 'out';
            app.UIAxes_9.TickLength = [0.02 0.025];
            app.UIAxes_9.GridAlpha = 0.15;
            app.UIAxes_9.MinorGridAlpha = 0.25;
            app.UIAxes_9.LineWidth = 0.25;
            app.UIAxes_9.XGrid = 'on';
            app.UIAxes_9.YGrid = 'on';
            app.UIAxes_9.ZGrid = 'on';
            app.UIAxes_9.Position = [0 -200 197 185];
            app.UIAxes_9.Visible = 'off';
            delete(get(app.UIAxes_9,'child'));
            
        end

        function axes_k3x3(app)
            title(app.UIAxes, 'k vs OD_{RX}')
            xlabel(app.UIAxes, 'd/OD_{RX}')
            ylabel(app.UIAxes, 'k (x10^-2)')
            app.UIAxes.FontName = 'Arial';
            app.UIAxes.FontSize = 10;
            app.UIAxes.TickDir = 'out';
            app.UIAxes.TickLength = [0.02 0.025];
            app.UIAxes.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.LineWidth = 0.25;
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Position = [36 535 197 185];

            % UIAxes_2
            title(app.UIAxes_2, 'k vs ID_{RX}')
            xlabel(app.UIAxes_2, 'ID_{RX}/OD_{RX}')
            ylabel(app.UIAxes_2, 'k (x10^-2)')
            app.UIAxes_2.FontName = 'Arial';
            app.UIAxes_2.FontSize = 10;
            app.UIAxes_2.TickDir = 'out';
            app.UIAxes_2.TickLength = [0.02 0.025];
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_2.LineWidth = 0.25;
            app.UIAxes_2.XGrid = 'on';
            app.UIAxes_2.YGrid = 'on';
            app.UIAxes_2.Position = [284 533 197 185];

            % UIAxes_3
            title(app.UIAxes_3, 'k vs OD_{TX}')
            xlabel(app.UIAxes_3, 'OD_{TX}/OD_{RX}')
            ylabel(app.UIAxes_3, 'k (x10^-2)')
            app.UIAxes_3.FontName = 'Arial';
            app.UIAxes_3.FontSize = 10;
            app.UIAxes_3.TickDir = 'out';
            app.UIAxes_3.TickLength = [0.02 0.025];
            app.UIAxes_3.XTick = [0 2.5 5 7.5 10 12.5 15];
            app.UIAxes_3.XTickLabel = {'0'; '2.5'; '5'; '7.5'; '10'; '12.5'; '15'};
            app.UIAxes_3.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_3.LineWidth = 0.25;
            app.UIAxes_3.XGrid = 'on';
            app.UIAxes_3.YGrid = 'on';
            app.UIAxes_3.Position = [530 527 197 185];

            % UIAxes_4
            title(app.UIAxes_4, 'k vs OD_{RX} and ID_{RX}')
            xlabel(app.UIAxes_4, 'd/OD_{RX}')
            ylabel(app.UIAxes_4, 'ID_{RX}/OD_{RX}')
            app.UIAxes_4.FontName = 'Arial';
            app.UIAxes_4.FontSize = 10;
            app.UIAxes_4.TickDir = 'out';
            app.UIAxes_4.TickLength = [0.02 0.025];
            app.UIAxes_4.GridAlpha = 0.15;
            app.UIAxes_4.MinorGridAlpha = 0.25;
            app.UIAxes_4.LineWidth = 0.25;
            app.UIAxes_4.XGrid = 'on';
            app.UIAxes_4.YGrid = 'on';
            app.UIAxes_4.ZGrid = 'on';
            app.UIAxes_4.Position = [37 330 197 185];

            % UIAxes_5
            title(app.UIAxes_5, 'k vs OD_{RX} and OD_{TX}')
            xlabel(app.UIAxes_5, 'd/OD_{RX}')
            ylabel(app.UIAxes_5, 'OD_{TX}/OD_{RX}')
            app.UIAxes_5.FontName = 'Arial';
            app.UIAxes_5.FontSize = 10;
            app.UIAxes_5.TickDir = 'out';
            app.UIAxes_5.TickLength = [0.02 0.025];
            app.UIAxes_5.GridAlpha = 0.15;
            app.UIAxes_5.MinorGridAlpha = 0.25;
            app.UIAxes_5.LineWidth = 0.25;
            app.UIAxes_5.XGrid = 'on';
            app.UIAxes_5.YGrid = 'on';
            app.UIAxes_5.ZGrid = 'on';
            app.UIAxes_5.Position = [284 330 197 185];

            % UIAxes_6
            title(app.UIAxes_6, 'k vs OD_{RX} and ID_{TX}')
            xlabel(app.UIAxes_6, 'd/OD_{RX}')
            ylabel(app.UIAxes_6, 'ID_{TX}/OD_{TX}')
            app.UIAxes_6.FontName = 'Arial';
            app.UIAxes_6.FontSize = 10;
            app.UIAxes_6.TickDir = 'out';
            app.UIAxes_6.TickLength = [0.02 0.025];
            app.UIAxes_6.GridAlpha = 0.15;
            app.UIAxes_6.MinorGridAlpha = 0.25;
            app.UIAxes_6.LineWidth = 0.25;
            app.UIAxes_6.XGrid = 'on';
            app.UIAxes_6.YGrid = 'on';
            app.UIAxes_6.ZGrid = 'on';
            app.UIAxes_6.Position = [528 330 197 185];

            % UIAxes_7
            title(app.UIAxes_7, 'ID_{RX} for maximum k')
            xlabel(app.UIAxes_7, 'd/OD_{RX}')
            ylabel(app.UIAxes_7, 'ID_{RX}/OD_{RX}')
            app.UIAxes_7.FontName = 'Arial';
            app.UIAxes_7.FontSize = 10;
            app.UIAxes_7.TickDir = 'out';
            app.UIAxes_7.TickLength = [0.02 0.025];
            app.UIAxes_7.GridAlpha = 0.15;
            app.UIAxes_7.MinorGridAlpha = 0.25;
            app.UIAxes_7.LineWidth = 0.25;
            app.UIAxes_7.XGrid = 'on';
            app.UIAxes_7.YGrid = 'on';
            app.UIAxes_7.ZGrid = 'on';
            app.UIAxes_7.Position = [37 127 197 185];

            % UIAxes_8
            title(app.UIAxes_8, 'OD_{TX} for maximum k')
            xlabel(app.UIAxes_8, 'd/OD_{RX}')
            ylabel(app.UIAxes_8, 'OD_{TX}/OD_{RX}')
            app.UIAxes_8.FontName = 'Arial';
            app.UIAxes_8.FontSize = 10;
            app.UIAxes_8.TickDir = 'out';
            app.UIAxes_8.TickLength = [0.02 0.025];
            app.UIAxes_8.GridAlpha = 0.15;
            app.UIAxes_8.MinorGridAlpha = 0.25;
            app.UIAxes_8.LineWidth = 0.25;
            app.UIAxes_8.XGrid = 'on';
            app.UIAxes_8.YGrid = 'on';
            app.UIAxes_8.ZGrid = 'on';
            app.UIAxes_8.Position = [283 127 197 185];

            % UIAxes_9
            title(app.UIAxes_9, 'ID_{TX} for maximum k')
            xlabel(app.UIAxes_9, 'd/OD_{RX}')
            ylabel(app.UIAxes_9, 'ID_{TX}/OD_{TX}')
            app.UIAxes_9.FontName = 'Arial';
            app.UIAxes_9.FontSize = 10;
            app.UIAxes_9.TickDir = 'out';
            app.UIAxes_9.TickLength = [0.02 0.025];
            app.UIAxes_9.GridAlpha = 0.15;
            app.UIAxes_9.MinorGridAlpha = 0.25;
            app.UIAxes_9.LineWidth = 0.25;
            app.UIAxes_9.XGrid = 'on';
            app.UIAxes_9.YGrid = 'on';
            app.UIAxes_9.ZGrid = 'on';
            app.UIAxes_9.Position = [527 127 197 185];

        end
        
        function results = showk_odrx(app)
            d = app.dmmEditField.Value*1000;
            % d: distance between the coils
            % d_per_od_rx_ = [0.1,0.3,0.5,0.7,0.9,1,1.5,2,2.5,3];
            % od_rx_ = d./d_per_od_rx_;
            od_rx_ = (1:5:51)*1000                          ;
            d_per_od_rx_ = d./od_rx_;
            od_tx_ = (1:1:50)*1000;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 10;
            
            for i = 1 : len_od_rx_
                i
                for j = 1 : len_od_tx_
                    for l = 1 : len_id_rx_
                        for m = 1 : len_id_tx_
                            od_rx = od_rx_(i);
                            od_tx = od_tx_(j);
                            id_rx = od_rx * l / (len_id_rx_+1);
                            id_tx = od_tx * m/ (len_id_tx_+1);
                            t_rx = 30;
                            t_tx = 500;
                            n_rx = 5;
                            n_tx = 5;
                            s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                            s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                            k(i,j,l,m) = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                            od_rx__(i,j,l,m)=od_rx;
                            od_tx__(i,j,l,m)=od_tx;
                            id_rx__(i,j,l,m)=id_rx;
                            id_tx__(i,j,l,m)=id_tx;
                        end
                    end
                end
            end
            
            plot(app.UIAxes,d_per_od_rx_,max(k,[],[2,3,4])*100)
            xlim(app.UIAxes,[0 3])
        end
        function results = showk_idrx(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = app.dmmEditField.Value*1000;
            od_rx = d/app.d_ODRXEditField.Value;
            od_tx_ = od_rx : (5*sqrt(od_rx^2+d^2)-od_rx)/10 : 5*sqrt(od_rx^2+d^2); 
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 10;
            for j = 1 : len_od_tx_
                for l = 1 : len_id_rx_
                    for m = 1 : len_id_tx_
                        od_tx = od_tx_(j);
                        id_rx = od_rx * l / (len_id_rx_+1);
                        id_tx = od_tx * m/ (len_id_tx_+1);
                        t_rx = 30;
                        t_tx = 500;
                        n_rx = 5;
                        n_tx = 5;
                        s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                        s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                        k(j,l,m) = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                        od_tx__(j,l,m)=od_tx;
                        id_rx__(j,l,m)=id_rx;
                        id_tx__(j,l,m)=id_tx;
                    end
                end
            end
            plot(app.UIAxes_2,(1:len_id_rx_)/(len_id_rx_+1),max(k,[],[1,3])*100)
            hold(app.UIAxes_2,'on');plot(app.UIAxes_2,0,0)
            %ylim(app.UIAxes_2,[0 max(ylim(app.UIAxes_2))])        
        end
        function results = showk_odtx(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = app.dmmEditField.Value*1000;
            od_rx = d/app.d_ODRXEditField.Value;
            id_rx = od_rx*app.IDRX_ODRXEditField_2.Value;
            od_tx_ = 0.1*od_rx : (max(5*sqrt(od_rx^2+d^2),15*od_rx)-od_rx)/100 : max(5*sqrt(od_rx^2+d^2),15*od_rx); 
            len_od_tx_ = length(od_tx_);
            len_id_tx_ = 100;
            for j = 1 : len_od_tx_
                for l = 1 : len_id_tx_
                    od_tx = od_tx_(j);
                    id_tx = od_tx * l/ (len_id_tx_+1);
                    t_rx = 30;
                    t_tx = 500;
                    n_rx = 5;
                    n_tx = 5;
                    s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                    s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                    k(j,l) = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                    od_tx__(j,l)=od_tx;
                    id_tx__(j,l)=id_tx;
                end
            end
            plot(app.UIAxes_3,od_tx_/od_rx,max(k,[],[2])*100)
            hold(app.UIAxes_3,'on');plot(app.UIAxes_2,0,0)
            xlim(app.UIAxes_3,[0 15])        
        end
        function results = showk_idrx_3d(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = app.dmmEditField.Value*1000;
            d_per_od_rx_min = app.d_ODRXEditField.Value;
            d_per_od_rx_ = d_per_od_rx_min:(3-d_per_od_rx_min)/10:3;
            od_rx_ = d./d_per_od_rx_;
            od_tx_ = (1:1:50)*1000;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 10;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                for l = 1 : len_id_rx_
                    k_max = 0;
                    id_rx = od_rx * l / (len_id_rx_+1);
                    for j = 1 : len_od_tx_
                        od_tx = od_tx_(j);
                        for m = 1 : len_id_tx_
                            id_tx = od_tx * m/ (len_id_tx_+1);
                            t_rx = 30;
                            t_tx = 500;
                            n_rx = 5;
                            n_tx = 5;
                            s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                            s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                            k_ = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                            if k_ > k_max
                                k_max = k_;
                            end
                        end
                    end
                    k(i,l) = k_max;
                    od_rx__(i,l) = od_rx;
                    id_rx__(i,l) = id_rx;
                end
            end
            mesh(app.UIAxes_4,id_rx__./od_rx__,d./od_rx__,k*100)
            hold(app.UIAxes_4,'on');plot3(app.UIAxes_4,0,0,0)
            ylim(app.UIAxes_4,[d_per_od_rx_min 3])        
            view(app.UIAxes_4,[-1,-2,1])
            axis(app.UIAxes_4,'ij')
            
            xlabel(app.UIAxes_4,'ID_{RX}/OD_{RX}');
            ylabel(app.UIAxes_4,'d/OD_{RX}');
            zlabel(app.UIAxes_4,'k (x 10^2)');
            title(app.UIAxes_4,'k vs OD_{RX} & ID_{RX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_4,flipud(map_n))
            %colorbar(app.UIAxes_4)
        end
        function results = showk_odtx_3d(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = app.dmmEditField.Value*1000;
            d_per_od_rx_min = app.d_ODRXEditField.Value;
            d_per_od_rx_ = d_per_od_rx_min:(3-d_per_od_rx_min)/10:3;
            od_rx_ = d./d_per_od_rx_;
            id_rx_per_od_rx = app.IDRX_ODRXEditField_2.Value;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = 20;
            len_id_tx_ = 10;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                id_rx = od_rx * id_rx_per_od_rx;
                od_tx_ = od_rx * [0.1 0.3 0.5 0.7 1 1.5 2.1 (3:15)]; 

                for j = 1 : len_od_tx_
                    od_tx = od_tx_(j);
                    k_max = 0;
                    for m = 1 : len_id_tx_
                        id_tx = od_tx * m/ (len_id_tx_+1);
                        t_rx = 30;
                        t_tx = 500;
                        n_rx = 5;
                        n_tx = 5;
                        s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                        s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                        k_ = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                        if k_ > k_max
                            k_max = k_;
                        end
                    end
                    k(i,j) = k_max;
                    od_rx__(i,j) = od_rx;
                    od_tx__(i,j) = od_tx;
                end
            end
            mesh(app.UIAxes_5,od_tx__./od_rx__,d./od_rx__,k*100)
            hold(app.UIAxes_5,'on');plot3(app.UIAxes_5,0,0,0)
            ylim(app.UIAxes_5,[d_per_od_rx_min 3])        
            view(app.UIAxes_5,[-1,-2,1])
            axis(app.UIAxes_5,'ij')
            
            xlabel(app.UIAxes_5,'OD_{TX}/OD_{RX}');
            ylabel(app.UIAxes_5,'d/OD_{RX}');
            zlabel(app.UIAxes_5,'k (x 10^2)');
            title(app.UIAxes_5,'k vs OD_{RX} & ID_{RX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_5,flipud(map_n))
            %colorbar(app.UIAxes_5)
        end
        
        function results = showk_idtx_3d(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = app.dmmEditField.Value*1000;
            d_per_od_rx_min = app.d_ODRXEditField.Value;
            d_per_od_rx_ = d_per_od_rx_min:(3-d_per_od_rx_min)/10:3;
            od_rx_ = d./d_per_od_rx_;
            od_tx_per_od_rx = app.ODTX_ODRXEditField.Value;
            id_rx_per_od_rx = app.IDRX_ODRXEditField_2.Value;
            len_od_rx_ = length(od_rx_);
            len_id_tx_ = 10;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                id_rx = od_rx * id_rx_per_od_rx;
                od_tx = od_rx * od_tx_per_od_rx;

                for m = 1 : len_id_tx_
                    id_tx = od_tx * m/ (len_id_tx_+1);
                    t_rx = 30;
                    t_tx = 500;
                    n_rx = 5;
                    n_tx = 5;
                    s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                    s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                    k(i,m) = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                    od_rx__(i,m) = od_rx;
                    id_tx__(i,m) = m/ (len_id_tx_+1);
                end
            end
            mesh(app.UIAxes_6,id_tx__,d./od_rx__,k*100)
            hold(app.UIAxes_6,'on');plot3(app.UIAxes_4,0,0,0)
            ylim(app.UIAxes_6,[d_per_od_rx_min 3])        
            view(app.UIAxes_6,[-1,-2,1])
            axis(app.UIAxes_6,'ij')
            
            xlabel(app.UIAxes_6,'ID_{TX}/OD_{TX}');
            ylabel(app.UIAxes_6,'d/OD_{RX}');
            zlabel(app.UIAxes_6,'k (x 10^2)');
            title(app.UIAxes_6,'k vs OD_{RX} & OD_{TX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_6,flipud(map_n))
            %colorbar(app.UIAxes_6)
        end

        function results = show_idrx_odrx(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = 15000%app.dmmEditField.Value*1000;
            d_per_od_rx_ = 0.1:0.1:3;
            od_rx_ = d./d_per_od_rx_;
            od_tx_ = (1:1:50)*1000;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 10;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                for l = 1 : len_id_rx_
                    k_max = 0;
                    id_rx = od_rx * l / (len_id_rx_+1);
                    for j = 1 : len_od_tx_
                        od_tx = od_tx_(j);
                        for m = 1 : len_id_tx_
                            id_tx = od_tx * m/ (len_id_tx_+1);
                            t_rx = 30;
                            t_tx = 500;
                            n_rx = 5;
                            n_tx = 5;
                            s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                            s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                            k_ = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                            if k_ > k_max
                                k_max = k_;
                            end
                        end
                    end
                    k(i,l) = k_max;
                    od_rx__(i,l) = od_rx;
                    id_rx__(i,l) = id_rx;
                end
            end
            
            for i = 1 : len_od_rx_
                [k_max(i), k_max_indice(i)] = max(k(i,:));
                id_rx_max(i) = id_rx__(i,k_max_indice(i));
                threshold=0.95*k_max(i);
                j=0;
                id_rx_over_th = 0;
                for l = 1 : len_id_rx_
                    if k(i,l)>threshold
                        j=j+1;
                        id_rx_over_th(j) = id_rx__(i,l);
                    end
                end
                id_rx_high(i) = max(id_rx_over_th);
                id_rx_low(i) = min(id_rx_over_th);
            end

            c_=ones(1,len_od_rx_*2);
            hold(app.UIAxes_7,'on');
            patch(app.UIAxes_7,[d./od_rx_,fliplr(d./od_rx_)],[id_rx_low./od_rx_,fliplr(id_rx_high./od_rx_)],[c_])
            plot(app.UIAxes_7,d./od_rx_,id_rx_low./od_rx_,'k--')
            plot(app.UIAxes_7,d./od_rx_,id_rx_high./od_rx_,'k--')
            plot(app.UIAxes_7,d./od_rx_,id_rx_max./od_rx_,'b','LineWidth',2)
            shading(app.UIAxes_7,"faceted")
            
            xlim(app.UIAxes_7,[0 3])        
            ylim(app.UIAxes_7,[0,1])
            
            ylabel(app.UIAxes_7,'ID_{RX}/OD_{RX}');
            xlabel(app.UIAxes_7,'d/OD_{RX}');
            title(app.UIAxes_7,'ID_{RX} for maximum k vs OD_{RX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_7,flipud(map_n));
            caxis(app.UIAxes_7,[0,1])
        end

        function results = show_odtx_odrx(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = 15000%app.dmmEditField.Value*1000;
            d_per_od_rx_ = 0.1:0.1:3;
            od_rx_ = d./d_per_od_rx_;
            od_tx_ = (1:1:50)*1000;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 10;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                for j = 1 : len_od_tx_
                    od_tx = od_tx_(j);
                    
                    for l = 1 : len_id_rx_
                        k_max = 0;
                        id_rx = od_rx * l / (len_id_rx_+1);
                    
                        for m = 1 : len_id_tx_
                            id_tx = od_tx * m/ (len_id_tx_+1);
                            t_rx = 30;
                            t_tx = 500;
                            n_rx = 5;
                            n_tx = 5;
                            s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                            s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                            k_ = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                            if k_ > k_max
                                k_max = k_;
                            end
                        end
                    end
                    k(i,j) = k_max;
                    od_rx__(i,j) = od_rx;
                    od_tx__(i,j) = od_tx;
                end
            end
            
            for i = 1 : len_od_rx_
                [k_max(i), k_max_indice(i)] = max(k(i,:));
                od_tx_max(i) = od_tx__(i,k_max_indice(i));
                threshold=0.95*k_max(i);
                l=0;
                od_tx_over_th = 0;
                for j = 1 : len_od_tx_
                    if k(i,j)>threshold
                        l=l+1;
                        od_tx_over_th(l) = od_tx__(i,j);
                    end
                end
                od_tx_high(i) = max(od_tx_over_th);
                od_tx_low(i) = min(od_tx_over_th);
            end

            c_=ones(1,len_od_rx_*2);
            hold(app.UIAxes_8,'on');
            patch(app.UIAxes_8,[d./od_rx_,fliplr(d./od_rx_)],[od_tx_low./od_rx_,fliplr(od_tx_high./od_rx_)],[c_])
            plot(app.UIAxes_8,d./od_rx_,od_tx_low./od_rx_,'k--')
            plot(app.UIAxes_8,d./od_rx_,od_tx_high./od_rx_,'k--')
            plot(app.UIAxes_8,d./od_rx_,od_tx_max./od_rx_,'b','LineWidth',2)
            shading(app.UIAxes_8,"faceted")
            
            xlim(app.UIAxes_8,[0 3])        
            %ylim(app.UIAxes_8,[0,1])
            
            ylabel(app.UIAxes_8,'OD_{TX}/OD_{RX}');
            xlabel(app.UIAxes_8,'d/OD_{RX}');
            title(app.UIAxes_8,'OD_{TX} for maximum k vs OD_{RX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_8,flipud(map_n));
            caxis(app.UIAxes_8,[0,1])
        end

        function results = show_idtx_odtx(app)
            clear d k od_tx__ od_rx__ id_tx__ id_rx__;
            d = 15000%app.dmmEditField.Value*1000;
            d_per_od_rx_ = 0.1:0.1:3;
            od_rx_ = d./d_per_od_rx_;
            od_tx_ = (1:1:50)*1000;
            len_od_rx_ = length(od_rx_);
            len_od_tx_ = length(od_tx_);
            len_id_rx_ = 10;
            len_id_tx_ = 50;
            for i = 1 : len_od_rx_
                i
                od_rx = od_rx_(i);
                for m = 1 : len_id_tx_
                    for j = 1 : len_od_tx_
                        od_tx = od_tx_(j);
                        id_tx = od_tx * m/ (len_id_tx_+1);
                        for l = 1 : len_id_rx_
                            k_max = 0;
                            id_rx = od_rx * l / (len_id_rx_+1);
                            t_rx = 30;
                            t_tx = 500;
                            n_rx = 5;
                            n_tx = 5;
                            s_rx = (od_rx-id_rx)/2/(2*n_rx-1);
                            s_tx = (od_tx-id_tx)/2/(2*n_tx-1);
                            k_ = kFilament(s_rx,n_rx,od_rx,id_rx,s_tx,n_tx,od_tx,id_tx,d,30,500,13.56e6,8.8417e-04,3.4483e-05);
                            if k_ > k_max
                                k_max = k_;
                                id_tx_max_ = id_tx/od_tx;
                            end
                        end
                    end
                    k(i,m) = k_max;
                    od_rx__(i,m) = od_rx;
                    id_tx__(i,m) = id_tx_max_;
                end
            end
            
            for i = 1 : len_od_rx_
                [k_max(i), k_max_indice(i)] = max(k(i,:));
                id_tx_max(i) = id_tx__(i,k_max_indice(i));
                threshold=0.95*k_max(i);
                l=0;
                id_tx_over_th = 0;
                for m = 1 : len_id_tx_
                    if k(i,m)>threshold
                        l=l+1;
                        id_tx_over_th(l) = id_tx__(i,m);
                    end
                end
                id_tx_high(i) = max(id_tx_over_th);
                id_tx_low(i) = min(id_tx_over_th);
            end

            c_=ones(1,len_od_rx_*2);
            hold(app.UIAxes_9,'on');
            patch(app.UIAxes_9,[d./od_rx_,fliplr(d./od_rx_)],[id_tx_low,fliplr(id_tx_high)],[c_])
            plot(app.UIAxes_9,d./od_rx_,id_tx_low,'k--')
            plot(app.UIAxes_9,d./od_rx_,id_tx_high,'k--')
            plot(app.UIAxes_9,d./od_rx_,id_tx_max,'b','LineWidth',2)
            shading(app.UIAxes_9,"faceted")
            
            xlim(app.UIAxes_9,[0 3])        
            %ylim(app.UIAxes_9,[0,1])
            
            ylabel(app.UIAxes_9,'ID_{TX}/OD_{TX}');
            xlabel(app.UIAxes_9,'d/OD_{RX}');
            title(app.UIAxes_9,'ID_{TX} for maximum k vs OD_{RX}')           
            
            map_s = [183 36 103;
                   246 139 31;
                   102 45 145;
                   82 79 161;
                   0 166 81]/255;
                   
            for i = 1:32
              st = floor(i/8.01);
              en = ceil(i/8.01);
              map_n(i,:) = map_s(st+1,:) * (en-i/8) + map_s(en+1,:) * (i/8-st);
            end
            
            colormap(app.UIAxes_9,flipud(map_n));
            caxis(app.UIAxes_9,[0,1])
        
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {756, 756, 756};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {756, 756};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {147, '1x', 337};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: dmmEditField
        function dmmEditFieldValueChanged(app, event)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Value changed function: d_ODRXEditField
        function d_ODRXEditFieldValueChanged(app, event)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Value changed function: IDRX_ODRXEditField_2
        function IDRX_ODRXEditField_2ValueChanged(app, event)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Value changed function: ODTX_ODRXEditField
        function ODTX_ODRXEditFieldValueChanged(app, event)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Value changed function: IDTX_ODTXEditField
        function IDTX_ODTXEditFieldValueChanged(app, event)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);
        end

        % Button pushed function: PickdODandIDButton
        function PickdODandIDButtonPushed(app, event)
            axes_k3x3(app)
            rehash();
            addpath("./pte_calculation;./plot;./coil_model");
            showk_odrx(app);
            showk_idrx(app);
            showk_odtx(app);
            showk_idrx_3d(app);
            showk_odtx_3d(app);
            showk_idtx_3d(app);
            show_idrx_odrx(app);
            show_odtx_odrx(app);
            show_idtx_odtx(app);            
        end

        % Button pushed function: SearchbestcoilButton
        function SearchbestcoilButtonPushed(app, event)
            axes_pte2x4(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PickdODandIDButton = uibutton(app.LeftPanel, 'push');
            app.PickdODandIDButton.ButtonPushedFcn = createCallbackFcn(app, @PickdODandIDButtonPushed, true);
            app.PickdODandIDButton.Text = 'Pick d, OD, and ID';
            app.SearchbestcoilButton = uibutton(app.LeftPanel, 'push');
            app.SearchbestcoilButton.ButtonPushedFcn = createCallbackFcn(app, @SearchbestcoilButtonPushed, true);
            app.SearchbestcoilButton.Text = 'Search best coil';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'k vs OD_{RX}')
            xlabel(app.UIAxes, 'd/OD_{RX}')
            ylabel(app.UIAxes, 'k (x10^2)')
            app.UIAxes.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'k vs ID_{RX}')
            xlabel(app.UIAxes_2, 'ID_{RX}/OD_{RX}')
            ylabel(app.UIAxes_2, 'k (x10^2)')
            app.UIAxes_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_2.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_3 = uiaxes(app.CenterPanel);
            title(app.UIAxes_3, 'k vs OD_{TX}')
            xlabel(app.UIAxes_3, 'OD_{TX}/OD_{RX}')
            ylabel(app.UIAxes_3, 'k (x10^2)')
            app.UIAxes_3.XTick = [0 2.5 5 7.5 10 12.5 15];
            app.UIAxes_3.XTickLabel = {'0'; '2.5'; '5'; '7.5'; '10'; '12.5'; '15'};
            app.UIAxes_3.ZTick = [0 0.5 1 1.5 2 2.5 3];
            app.UIAxes_4 = uiaxes(app.CenterPanel);
            title(app.UIAxes_4, 'k vs ID_{RX}')
            xlabel(app.UIAxes_4, 'd/OD_{RX}')
            ylabel(app.UIAxes_4, 'ID_{RX}/OD_{RX}')
            app.UIAxes_5 = uiaxes(app.CenterPanel);
            title(app.UIAxes_5, 'k vs ID_{RX}')
            xlabel(app.UIAxes_5, 'd/OD_{RX}')
            ylabel(app.UIAxes_5, 'ID_{RX}/OD_{RX}')
            app.UIAxes_6 = uiaxes(app.CenterPanel);
            title(app.UIAxes_6, 'k vs ID_{RX}')
            xlabel(app.UIAxes_6, 'd/OD_{RX}')
            ylabel(app.UIAxes_6, 'ID_{RX}/OD_{RX}')
            app.UIAxes_7 = uiaxes(app.CenterPanel);
            title(app.UIAxes_7, 'k vs ID_{RX}')
            xlabel(app.UIAxes_7, 'd/OD_{RX}')
            ylabel(app.UIAxes_7, 'ID_{RX}/OD_{RX}')
            app.UIAxes_8 = uiaxes(app.CenterPanel);
            title(app.UIAxes_8, 'k vs ID_{RX}')
            xlabel(app.UIAxes_8, 'd/OD_{RX}')
            ylabel(app.UIAxes_8, 'ID_{RX}/OD_{RX}')
            app.UIAxes_9 = uiaxes(app.CenterPanel);
            title(app.UIAxes_9, 'k vs ID_{RX}')
            xlabel(app.UIAxes_9, 'd/OD_{RX}')
            ylabel(app.UIAxes_9, 'ID_{RX}/OD_{RX}')
            app.RightPanel = uipanel(app.GridLayout);
            app.dmmEditFieldLabel = uilabel(app.RightPanel);
            app.dmmEditFieldLabel.Text = 'd (mm)';
            app.dmmEditField = uieditfield(app.RightPanel, 'numeric');
            app.dmmEditField.ValueChangedFcn = createCallbackFcn(app, @dmmEditFieldValueChanged, true);
            app.dmmEditField.Value = 15;
            app.d_ODRXEditFieldLabel = uilabel(app.RightPanel);
            app.d_ODRXEditFieldLabel.Text = 'd/OD   ';
            app.d_ODRXEditField = uieditfield(app.RightPanel, 'numeric');
            app.d_ODRXEditField.ValueChangedFcn = createCallbackFcn(app, @d_ODRXEditFieldValueChanged, true);
            app.d_ODRXEditField.Value = 1;
            app.d_ODRXEditFieldLabel_2 = uilabel(app.RightPanel);
            app.d_ODRXEditFieldLabel_2.Text = 'RX';
            app.IDRX_ODRXEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.IDRX_ODRXEditField_2.ValueChangedFcn = createCallbackFcn(app, @IDRX_ODRXEditField_2ValueChanged, true);
            app.IDRX_ODRXEditField_2.Value = 0.8;
            app.IDRX_ODRXEditFieldLabel = uilabel(app.RightPanel);
            app.IDRX_ODRXEditFieldLabel.Text = 'ID   /OD   ';
            app.IDRX_ODRXEditFieldLabel_2 = uilabel(app.RightPanel);
            app.IDRX_ODRXEditFieldLabel_2.Text = 'RX';
            app.IDRX_ODRXEditFieldLabel_3 = uilabel(app.RightPanel);
            app.IDRX_ODRXEditFieldLabel_3.Text = 'RX';
            app.ODTX_ODRXEditField = uieditfield(app.RightPanel, 'numeric');
            app.ODTX_ODRXEditField.ValueChangedFcn = createCallbackFcn(app, @ODTX_ODRXEditFieldValueChanged, true);
            app.ODTX_ODRXEditField.Value = 3.3;
            app.IDTX_ODTXEditField = uieditfield(app.RightPanel, 'numeric');
            app.IDTX_ODTXEditField.ValueChangedFcn = createCallbackFcn(app, @IDTX_ODTXEditFieldValueChanged, true);
            app.IDTX_ODTXEditField.Value = 0.8;
            app.ODTX_ODRXEditFieldLabel = uilabel(app.RightPanel);
            app.ODTX_ODRXEditFieldLabel.Text = 'OD   /OD   ';
            app.IDTX_ODTXEditFieldLabel = uilabel(app.RightPanel);
            app.IDTX_ODTXEditFieldLabel.Text = 'ID   /OD   ';
            app.ODTX_ODRXEditFieldLabel_2 = uilabel(app.RightPanel);
            app.ODTX_ODRXEditFieldLabel_2.Text = 'TX';
            app.ODTX_ODRXEditFieldLabel_3 = uilabel(app.RightPanel);
            app.ODTX_ODRXEditFieldLabel_3.Text = 'RX';
            app.IDTX_ODTXEditFieldLabel_2 = uilabel(app.RightPanel);
            app.IDTX_ODTXEditFieldLabel_2.Text = 'TX';
            app.IDTX_ODTXEditFieldLabel_3 = uilabel(app.RightPanel);
            app.IDTX_ODTXEditFieldLabel_3.Text = 'TX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end